classdef appDisplayImage < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure            matlab.ui.Figure
		GridLayout          matlab.ui.container.GridLayout
		LeftPanel           matlab.ui.container.Panel
		DisplayImageButton  matlab.ui.control.Button
		ListBox             matlab.ui.control.ListBox
		ListBoxLabel        matlab.ui.control.Label
		RightPanel          matlab.ui.container.Panel
		UIAxes              matlab.ui.control.UIAxes
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			fileList = dir('*.png');
			app.ListBox.Items = {fileList.name};
		end

		% Button pushed function: DisplayImageButton
		function DisplayImageButtonPushed(app, event)
			% Get selected filename from listbox.
			selectedFileName = app.ListBox.Value;
			if isempty(selectedFileName)
				return;
			end
			theImage = imread(selectedFileName);
			imshow(theImage, 'Parent', app.UIAxes);
		end

		% Value changed function: ListBox
		function ListBoxValueChanged(app, event)
% 			value = app.ListBox.Value;
						% Get selected filename from listbox.
			selectedFileName = app.ListBox.Value;
			if isempty(selectedFileName)
				return;
			end
			theImage = imread(selectedFileName);
			imshow(theImage, 'Parent', app.UIAxes);

		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.UIFigure.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {497, 497};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {470, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.GridLayout = uigridlayout(app.UIFigure);
			app.LeftPanel = uipanel(app.GridLayout);
			app.ListBoxLabel = uilabel(app.LeftPanel);
			app.ListBoxLabel.Text = 'List Box';
			app.ListBox = uilistbox(app.LeftPanel);
			app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
			app.DisplayImageButton = uibutton(app.LeftPanel, 'push');
			app.DisplayImageButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayImageButtonPushed, true);
			app.DisplayImageButton.Text = 'Display Image';
			app.RightPanel = uipanel(app.GridLayout);
			app.RightPanel.ButtonDownFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.UIAxes = uiaxes(app.RightPanel);
			title(app.UIAxes, 'Title')
			xlabel(app.UIAxes, 'X')
			ylabel(app.UIAxes, 'Y')
			zlabel(app.UIAxes, 'Z')
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = appDisplayImage

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end